classdef Anser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure  matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SystemTab                    matlab.ui.container.Tab
        ChannelselectPanel           matlab.ui.container.Panel
        ChannelSelectListBox         matlab.ui.control.ListBox
        HoldCtrltoselectmultiplechannelsLabel  matlab.ui.control.Label
        SystemconfigurationPanel     matlab.ui.container.Panel
        SystemversionButtonGroup     matlab.ui.container.ButtonGroup
        AnserButton                  matlab.ui.control.RadioButton
        DAQversionButtonGroup        matlab.ui.container.ButtonGroup
        NI621XButton                 matlab.ui.control.RadioButton
        MCC1608GXButton              matlab.ui.control.RadioButton
        BoardIDPanel                 matlab.ui.container.Panel
        EditField                    matlab.ui.control.EditField
        egDev1Board1etcLabel         matlab.ui.control.Label
        ConnectButton_2              matlab.ui.control.Button
        TextArea_2                   matlab.ui.control.TextArea
        OpenIGTLinkTab               matlab.ui.container.Tab
        NetworksettingsPanel         matlab.ui.container.Panel
        AddressEditFieldLabel        matlab.ui.control.Label
        AddressEditField             matlab.ui.control.EditField
        PortEditFieldLabel           matlab.ui.control.Label
        PortEditField                matlab.ui.control.NumericEditField
        ConnectButton                matlab.ui.control.Button
        StreamButton                 matlab.ui.control.Button
        ConnectionstatusPanel        matlab.ui.container.Panel
        TextArea                     matlab.ui.control.TextArea
        TransformPanel               matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        SensorselectDropDownLabel    matlab.ui.control.Label
        SensorselectDropDown         matlab.ui.control.DropDown
        CalibrationTab               matlab.ui.container.Tab
        CalibrationsettingsPanel     matlab.ui.container.Panel
        SensorselectDropDown_4Label  matlab.ui.control.Label
        SensorselectDropDown_4       matlab.ui.control.DropDown
        CalibrateButton              matlab.ui.control.Button
        CalibrationstatusPanel       matlab.ui.container.Panel
        TextArea_3                   matlab.ui.control.TextArea
        TrackingTab                  matlab.ui.container.Tab
        TestsensorPanel              matlab.ui.container.Panel
        SensorselectDropDown_2Label  matlab.ui.control.Label
        SensorselectDropDown_2       matlab.ui.control.DropDown
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        ZEditField                   matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        EditField_7                  matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        EditField_6                  matlab.ui.control.NumericEditField
        UITable_2                    matlab.ui.control.Table
        AcquireButton                matlab.ui.control.Button
        FFTTab                       matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        SensorselectDropDown_3Label  matlab.ui.control.Label
        SensorselectDropDown_3       matlab.ui.control.DropDown
        AcquireButton_2              matlab.ui.control.Button
        AnserEMTLabel                matlab.ui.control.Label
    end


    properties (Access = public)
        sys = struct(); % Description
    end


    methods (Access = private)

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
            chlist = cell2mat(app.ChannelSelectListBox.Value)';
            chlist = str2num(chlist);
            chlist_string = num2str(chlist);
            app.TextArea_2.Value = strcat('Channels selected: ',{' '},strjoin(app.ChannelSelectListBox.Value,', '));
            
            % Determine selected DAQ
            if app.NI621XButton.Value == 1
                daqtype = 'nidaq621Xoem';
            elseif app.MCC1608GXButton.Value == 1
                daqtype = 'mccdaq';
            end

            % Determine selected System Type
            if app.AnserButton.Value == 1
                systemtype = 'anser1';
            end
            
                        
            % Determine board ID
            boardid = app.EditField.Value;
            
           
            % Initialise system
            app.sys = fSysSetup(chlist,systemtype, daqtype, boardid);
            
            
            tempstr = app.TextArea_2.Value;
            tempstr = strcat(app.TextArea_2.Value,{' '},'Success!');
            app.TextArea_2.Value = tempstr;           
            
        end

        % Button pushed function: AcquireButton
        function AcquireButtonPushed(app, event)
            app.sys = fSysDAQUpdate(app.sys);
            app.sys = fGetSensorPosition(app.sys,1);
            
            position = app.sys.positionVector;
            matrix = app.sys.positionMatrix;
            
            app.XEditField.Value = position(1);
            app.YEditField.Value = position(2);
            app.ZEditField.Value = position(3);
            app.EditField_7.Value = position(4);
            app.EditField_6.Value = position(5);
            
            app.UITable_2.Data=matrix;
        end

        % Value changed function: SensorselectDropDown_2
        function SensorselectDropDown_2ValueChanged(app, event)
            value = app.SensorselectDropDown_2.Value;
            if ismember(str2num(value),app.sys.Sensors)
                app.AcquireButton.Enable = 'on';
            else
                app.AcquireButton.Enable = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure = uifigure;
            app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure.Name = 'Anser EMT: An open source electromagnetic tracking platform';
            app.TabGroup = uitabgroup(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure);
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.ChannelselectPanel = uipanel(app.SystemTab);
            app.ChannelselectPanel.Title = 'Channel select';
            app.ChannelSelectListBox = uilistbox(app.ChannelselectPanel);
            app.ChannelSelectListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.ChannelSelectListBox.Multiselect = 'on';
            app.ChannelSelectListBox.Value = {'1'};
            app.HoldCtrltoselectmultiplechannelsLabel = uilabel(app.ChannelselectPanel);
            app.HoldCtrltoselectmultiplechannelsLabel.Text = {'Hold <Ctrl> to select'; 'multiple channels'};
            app.SystemconfigurationPanel = uipanel(app.SystemTab);
            app.SystemconfigurationPanel.Title = 'System configuration';
            app.SystemversionButtonGroup = uibuttongroup(app.SystemconfigurationPanel);
            app.SystemversionButtonGroup.Title = 'System version';
            app.AnserButton = uiradiobutton(app.SystemversionButtonGroup);
            app.AnserButton.Text = 'Anser';
            app.AnserButton.Value = true;
            app.DAQversionButtonGroup = uibuttongroup(app.SystemconfigurationPanel);
            app.DAQversionButtonGroup.Title = 'DAQ version';
            app.NI621XButton = uiradiobutton(app.DAQversionButtonGroup);
            app.NI621XButton.Text = 'NI-621X';
            app.NI621XButton.Value = true;
            app.MCC1608GXButton = uiradiobutton(app.DAQversionButtonGroup);
            app.MCC1608GXButton.Text = 'MCC 1608GX';
            app.BoardIDPanel = uipanel(app.SystemconfigurationPanel);
            app.BoardIDPanel.Title = 'Board ID';
            app.EditField = uieditfield(app.BoardIDPanel, 'text');
            app.EditField.Value = 'Dev1';
            app.egDev1Board1etcLabel = uilabel(app.BoardIDPanel);
            app.egDev1Board1etcLabel.Text = '(eg. Dev1, Board1 etc.)';
            app.ConnectButton_2 = uibutton(app.SystemconfigurationPanel, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.TextArea_2 = uitextarea(app.SystemconfigurationPanel);
            app.TextArea_2.Editable = 'off';
            app.OpenIGTLinkTab = uitab(app.TabGroup);
            app.OpenIGTLinkTab.Title = 'OpenIGTLink';
            app.NetworksettingsPanel = uipanel(app.OpenIGTLinkTab);
            app.NetworksettingsPanel.Title = 'Network settings';
            app.AddressEditFieldLabel = uilabel(app.NetworksettingsPanel);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.NetworksettingsPanel, 'text');
            app.AddressEditField.Value = '127.0.0.1';
            app.PortEditFieldLabel = uilabel(app.NetworksettingsPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.NetworksettingsPanel, 'numeric');
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.Value = 18944;
            app.ConnectButton = uibutton(app.NetworksettingsPanel, 'push');
            app.ConnectButton.Text = 'Connect';
            app.StreamButton = uibutton(app.NetworksettingsPanel, 'push');
            app.StreamButton.Text = 'Stream';
            app.ConnectionstatusPanel = uipanel(app.OpenIGTLinkTab);
            app.ConnectionstatusPanel.Title = 'Connection status';
            app.TextArea = uitextarea(app.ConnectionstatusPanel);
            app.TransformPanel = uipanel(app.OpenIGTLinkTab);
            app.TransformPanel.Title = 'Transform';
            app.UITable = uitable(app.TransformPanel);
            app.UITable.ColumnName = {'x'; 'y'; 'z'; 't'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.SensorselectDropDownLabel = uilabel(app.TransformPanel);
            app.SensorselectDropDownLabel.Text = 'Sensor select';
            app.SensorselectDropDown = uidropdown(app.TransformPanel);
            app.SensorselectDropDown.Items = {};
            app.SensorselectDropDown.Value = {};
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrationsettingsPanel = uipanel(app.CalibrationTab);
            app.CalibrationsettingsPanel.Title = 'Calibration settings';
            app.SensorselectDropDown_4Label = uilabel(app.CalibrationsettingsPanel);
            app.SensorselectDropDown_4Label.Text = 'Sensor select';
            app.SensorselectDropDown_4 = uidropdown(app.CalibrationsettingsPanel);
            app.SensorselectDropDown_4.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.SensorselectDropDown_4.Value = 'Select';
            app.CalibrateButton = uibutton(app.CalibrationsettingsPanel, 'push');
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.CalibrationstatusPanel = uipanel(app.CalibrationTab);
            app.CalibrationstatusPanel.Title = 'Calibration status';
            app.TextArea_3 = uitextarea(app.CalibrationstatusPanel);
            app.TextArea_3.Editable = 'off';
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.TestsensorPanel = uipanel(app.TrackingTab);
            app.TestsensorPanel.Title = 'Test sensor';
            app.SensorselectDropDown_2Label = uilabel(app.TestsensorPanel);
            app.SensorselectDropDown_2Label.Text = 'Sensor select';
            app.SensorselectDropDown_2 = uidropdown(app.TestsensorPanel);
            app.SensorselectDropDown_2.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.SensorselectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SensorselectDropDown_2ValueChanged, true);
            app.SensorselectDropDown_2.Value = 'Select';
            app.XEditFieldLabel = uilabel(app.TestsensorPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.TestsensorPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.TestsensorPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.TestsensorPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.TestsensorPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.TestsensorPanel, 'numeric');
            app.Label_2 = uilabel(app.TestsensorPanel);
            app.Label_2.Text = 'θ';
            app.EditField_7 = uieditfield(app.TestsensorPanel, 'numeric');
            app.Label = uilabel(app.TestsensorPanel);
            app.Label.Text = 'φ';
            app.EditField_6 = uieditfield(app.TestsensorPanel, 'numeric');
            app.UITable_2 = uitable(app.TestsensorPanel);
            app.UITable_2.ColumnName = {'X'; 'Y'; 'Z'; 't'};
            app.UITable_2.RowName = {};
            app.AcquireButton = uibutton(app.TestsensorPanel, 'push');
            app.AcquireButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireButtonPushed, true);
            app.AcquireButton.Enable = 'off';
            app.AcquireButton.Text = 'Acquire';
            app.FFTTab = uitab(app.TabGroup);
            app.FFTTab.Title = 'FFT';
            app.UIAxes = uiaxes(app.FFTTab);
            title(app.UIAxes, 'Fast Fourier Transform')
            xlabel(app.UIAxes, 'Normalised frequency')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            app.SensorselectDropDown_3Label = uilabel(app.FFTTab);
            app.SensorselectDropDown_3Label.Text = 'Sensor select';
            app.SensorselectDropDown_3 = uidropdown(app.FFTTab);
            app.AcquireButton_2 = uibutton(app.FFTTab, 'push');
            app.AcquireButton_2.Text = 'Acquire';
            app.AnserEMTLabel = uilabel(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure);
            app.AnserEMTLabel.Text = 'Anser EMT';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Anser

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure)
        end
    end
end